classdef IlluMamutFigureGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GeneratefigureDropDownLabel  matlab.ui.control.Label
        GeneratefigureDropDown       matlab.ui.control.DropDown
        WheretoplotButtonGroup       matlab.ui.container.ButtonGroup
        inthisfigureButton           matlab.ui.control.RadioButton
        externalfigureButton         matlab.ui.control.RadioButton
        FiguregeneratorLabel         matlab.ui.control.Label
        plotButton                   matlab.ui.control.Button
        CroppannelPanel              matlab.ui.container.Panel
        firstframeSliderLabel        matlab.ui.control.Label
        firstframeSlider             matlab.ui.control.Slider
        lastframeSliderLabel         matlab.ui.control.Label
        lastframeSlider              matlab.ui.control.Slider
        cropButton                   matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        firsteditField               matlab.ui.control.NumericEditField
        lasteditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Data % Description
        Framelist % list of Framenames
        Tracklist %list of tracknames
        TrackdivisionLogical % which tracks are dividing
        SisterDistance = 0 
        NeighbourCalculated = 0 % tracks whether the neighbour has been calculated
        Tracklist_sub % tracks present in first frame (I think)
        Tracklist_sub_name %all name of sub_plots
        RawData % Description
        FirstFrame % Description
        LastFrame % Description
        StepAnalysisTable % Description
        TrackColorScheme = [0 0.4470 0.7410; 0.3010 0.7450 0.9330; 0.4660 0.6740 0.1880; 0.2 0.8 0.2] %the colorscheme used for normal moving non dividing, abnormal non div, normal div abnorma div
    end
    
    methods (Access = private)
        
        function ConvexVolumePlot(app,externalplot) %externalplot determine internal (0) or external (1) plotting 
            Volumes=zeros(length(app.Framelist),1);
            for t=1:length(app.Framelist)
                [~,Volumes(t)] = convhull(table2array(app.Data(app.Data.frame == app.Framelist(t),3:5)));
            end
            if externalplot
                figure;
                ax=gca;
            else
                ax = app.UIAxes;
            end
            
            plot(ax,Volumes)
            title("Volume of Convex Hull")
            xlabel("Frame")
            ylabel("µm^3")
        end
        
        function SisterDistancePlot(app,SisterDistance,where)
            if where
                figure;
                ax=gca;
            else
                ax = app.UIAxes;
            end
            colormaps_SD=distinguishable_colors(size(SisterDistance,2));
            plot(ax,1:(length(nonzeros(SisterDistance(:,1)))),nonzeros(SisterDistance(:,1)),'Color', colormaps_SD(1,:))

            title('Distance to sister cell after division')
            ylabel("µm")
            xlabel("Frames after division")

            hold(ax,'on')
            for i = 2:size(SisterDistance,2)
                plot(ax,1:(length(nonzeros(SisterDistance(:,i)))),nonzeros(SisterDistance(:,i)),'Color', colormaps_SD(i,:))
            end
            hold(ax,'off')
            lgd=legend(num2str(app.Tracklist(app.TrackdivisionLogical)),'Location','northeastoutside');
            title(lgd,"Track ID")
        end
        
        function NeighbourPlot(app,plottomake,where)
            
            if where
                figure;
                if ~strcmp(plottomake,'del_dvsn')
                    ax_l = subplot(1,3,1);
                    ax_m = subplot(1,3,2);
                    ax_h = subplot(1,3,3);
                else
                    ax_l = subplot(2,3,1);
                    ax_m = subplot(2,3,2);
                    ax_h = subplot(2,3,3);
                    ax_ld = subplot(2,3,4);
                    ax_md = subplot(2,3,5);
                    ax_hd = subplot(2,3,6);
                end
            else
                %subplot doesn't work in uiAxes at the moment, can maybe be fixed!    
                app.UIFigure.AutoResizeChildren = 'off';
                ax_l = subplot(1,3,1,'Parent',app.UIFigure);
                ax_m = subplot(1,3,2,'Parent',app.UIFigure);
                ax_h = subplot(1,3,3,'Parent',app.UIFigure);
            end
            %fix the axes
            xlim(ax_l, [0, max(app.Framelist)])
            xlim(ax_m, [0, max(app.Framelist)])
            xlim(ax_h, [0, max(app.Framelist)])
            
            if strcmp(plottomake, 'distall')
                ymax = max(app.Data.FirstNeighbourDistance);
                ymin = 0;
            else
                %need to run through the list to figure out the range
                largestpoint = zeros(length(app.Tracklist_sub_name),1);
                lowestpoint = zeros(length(app.Tracklist_sub_name),1);
                for k = 1:length(app.Tracklist_sub_name)
                    plot_data=[app.Data.frame(app.Data.TrackID_sub == app.Tracklist_sub_name(k)),app.Data.FirstNeighbourDistance(app.Data.TrackID_sub == app.Tracklist_sub_name(k))];
                    largestpoint(k) = max(plot_data(:,2)-plot_data(1,2));
                    lowestpoint(k) = min(plot_data(:,2)-plot_data(1,2));
                end
                ymax = max(largestpoint);
                ymin = min(lowestpoint);
            end
            ylim(ax_l, [ymin, ymax])
            ylim(ax_m, [ymin, ymax])
            ylim(ax_h, [ymin, ymax])
            hold(ax_l,'on')
            hold(ax_m,'on')
            hold(ax_h,'on')
            if strcmp(plottomake,'del_dvsn')
                ylim(ax_ld, [ymin, ymax])
                ylim(ax_md, [ymin, ymax])
                ylim(ax_hd, [ymin, ymax])
                hold(ax_ld,'on')
                hold(ax_md,'on')
                hold(ax_hd,'on')
                lowdivid=0;
                meddivid=0;
                highdivid=0;
            end
            high_threshold = ymax*2/3;
            med_threshold  = ymax*1/3;
            low=0;
            med=0;
            high=0;
            for k = 1:length(app.Tracklist_sub_name)
                plot_data=[app.Data.frame(app.Data.TrackID_sub == app.Tracklist_sub_name(k)),app.Data.FirstNeighbourDistance(app.Data.TrackID_sub == app.Tracklist_sub_name(k))];
                if sum(plot_data(:,2)) ~= 0
                    if ~strcmp(plottomake,'distall')
                        plot_data(:,2) = plot_data(:,2)-plot_data(1,2);
                    end
                
                    %determine axis
                    if max(plot_data(:,2)) > high_threshold
                        if strcmp(plottomake,'del_dvsn') && app.TrackdivisionLogical(app.Tracklist == max(app.Data.TrackID(app.Data.TrackID_sub == app.Tracklist_sub_name(k))))
                            current_axes = ax_hd;
                            highdivid = highdivid +1;
                        else
                            current_axes = ax_h;
                        end
                        high = high +1;
                    elseif max(plot_data(:,2)) > med_threshold
                        if strcmp(plottomake,'del_dvsn') && app.TrackdivisionLogical(app.Tracklist == max(app.Data.TrackID(app.Data.TrackID_sub == app.Tracklist_sub_name(k))))
                            current_axes = ax_md;
                            meddivid = meddivid + 1;
                        else
                            current_axes = ax_m;
                        end
                        med = med +1;
                    else
                        if strcmp(plottomake,'del_dvsn') && app.TrackdivisionLogical(app.Tracklist == max(app.Data.TrackID(app.Data.TrackID_sub == app.Tracklist_sub_name(k))))
                            current_axes = ax_ld;
                            lowdivid = lowdivid +1;
                        else
                            current_axes = ax_l;
                        end
                        low = low +1;
                    end
                    plot(current_axes,plot_data(:,1),plot_data(:,2))
    %                 if ~ishold(current_axes)
    %                     hold(current_axes,'on')
    %                 end
                end
            end
            switch plottomake
                case 'distall'
                    name = 'Distance to Neighbour first frame';
                case 'delta'
                    name = 'Distance to Neighbour first frame (normalized)';
                case 'del_dvsn'
                   name = 'Distance to Neighbour first frame (normalized)'; 
            end
            if strcmp(plottomake,'del_dvsn')
                med = med-meddivid;
                high = high-highdivid;
                low = low - lowdivid;
            end
            title(ax_l, {[name];['Close distance (' num2str(low) ' tracks)'];['max distance < ' num2str(med_threshold) ' µm']})
            title(ax_m, {[name];['Medium distance (' num2str(med) ' tracks)']; [num2str(med_threshold) ' µm < max distance < ' num2str(high_threshold) 'µm']})
            title(ax_h, {[name];['High distance (' num2str(high) ' tracks)']; [num2str(high_threshold) ' µm < max distance']})
            xlabel(ax_l,'Frame')
            xlabel(ax_m,'Frame')
            xlabel(ax_h,'Frame')
            ylabel(ax_l,'µm')
            ylabel(ax_m,'µm')
            ylabel(ax_h,'µm')
            if strcmp(plottomake,'del_dvsn')
                title(ax_ld,['Close distance (' num2str(lowdivid) ' tracks)'])
                title(ax_md,['Medium distance (' num2str(meddivid) ' tracks)'])
                title(ax_hd,['High distance (' num2str(highdivid) ' tracks)'])
                ylabel(ax_ld,{'Dividing cells'; 'µm'}, 'fontsize', 12);
                xlabel(ax_ld,'Frame')
                xlabel(ax_md,'Frame')
                xlabel(ax_hd,'Frame')
                ylabel(ax_l,{'non dividing cells'; 'µm'}, 'fontsize', 12)
                ylabel(ax_md,'µm')
                ylabel(ax_hd,'µm') 
            end
        end


        function flag = CalculateNeighbourh(app)
            app.Data.NearestNeighbourDistance = zeros(height(app.Data),1);
            app.Data.NearestNeighbourID = zeros(height(app.Data),1);
            % for now just need for first frame I think:
            
%             for t = 1:length(app.Framelist)
            SelectionVector = app.Data.frame == app.Framelist(1);
            IDs=app.Data.TrackID(SelectionVector);
            pos_x=(app.Data.pos_x(SelectionVector));
            pos_y=(app.Data.pos_y(SelectionVector));
            pos_z=(app.Data.pos_z(SelectionVector));
           
            x_dif = pos_x-pos_x';
            y_dif = pos_y-pos_y';
            z_dif = pos_z-pos_z';
           
            distances=(x_dif.^2+y_dif.^2+z_dif.^2).^0.5;
            distances_nan = (distances./distances).*distances;
            [distances_min,idx] = min(distances_nan);
            app.Data.NearestNeighbourDistance(SelectionVector) = distances_min;
            app.Data.NearestNeighbourID(SelectionVector) = IDs(idx); 
%                
%             end
            
            app.Data.FirstNeighbourDistance = zeros(height(app.Data),1);
            app.Tracklist_sub = app.Data.TrackID(app.Data.frame == app.Framelist(1));
            NearestNeighbour_first = app.Data.NearestNeighbourID(app.Data.frame == app.Framelist(1));
            for t = 1: length(app.Framelist)
                for k = 1:length(app.Tracklist_sub)
       
                    idx = app.Data.frame == app.Framelist(t) & app.Data.TrackID == app.Tracklist_sub(k);
                    idx_neighbour = app.Data.frame == app.Framelist(t) & app.Data.TrackID == NearestNeighbour_first(k);

                    if max(idx) && max(idx_neighbour)
                        distance_first = (((app.Data.pos_x(idx)-app.Data.pos_x(idx_neighbour)).^2+...
                                            (app.Data.pos_y(idx)-app.Data.pos_y(idx_neighbour)).^2+...
                                            (app.Data.pos_z(idx)-app.Data.pos_z(idx_neighbour)).^2).^0.5);
        
                        if size(distance_first) == size(app.Data.FirstNeighbourDistance(idx))
                            app.Data.FirstNeighbourDistance(idx) = distance_first;
                        elseif size(distance_first) >= size (app.Data.FirstNeighbourDistance(idx))
                            %fix problem where the first nearest neighbor has split up
                            app.Data.FirstNeighbourDistance(idx) = min(distance_first);
                        end
                    elseif max(idx)
                        app.Data.FirstNeighbourDistance(idx) = 0;
                    end

                 end
            end
            app.Tracklist_sub_name = unique(app.Data.TrackID_sub);
            flag = 1;
        end
        
        function distance = Calculate_moved(~,SubSetData,divide_idx)
            first = min(SubSetData.frame);
            last = max(SubSetData.frame);
            if ~divide_idx
                X=SubSetData.pos_x_corr(SubSetData.frame == last);
                X1=SubSetData.pos_x_corr(SubSetData.frame == first);
                Y=SubSetData.pos_y_corr(SubSetData.frame == last);
                Y1=SubSetData.pos_y_corr(SubSetData.frame == first);
                Z=SubSetData.pos_z_corr(SubSetData.frame == last);
                Z1=SubSetData.pos_z_corr(SubSetData.frame == first);
                distance = ((X-X1)^2+(Y-Y1)^2+(Z-Z1)^2)^0.5;
            end

        end


        function movementboxplot(app,plottomake)
            figure;
            ax_t=subplot(3,1,1);
            ax_m=subplot(3,1,2);
            ax_b=subplot(3,1,3);
            axx_all=[ax_t,ax_m,ax_b];
            switch plottomake
                case 'sphere'
                    boxplot(ax_t,app.Data.SphereMove_r, {app.Data.DivisionLogical,app.Data.TrackID},"PlotStyle","compact",'ColorGroup',app.Data.DivisionLogical)
                    title(ax_t, 'Movement for each track away from center (r)')
                    boxplot(ax_m,app.Data.SphereMove_azimute, {app.Data.DivisionLogical,app.Data.TrackID},'PlotStyle','compact','ColorGroup',app.Data.DivisionLogical)
                    title(ax_m, 'Movement for each track in equtorial plane (xy)')
                    boxplot(ax_b,app.Data.SphereMove_elevation, {app.Data.DivisionLogical,app.Data.TrackID},'PlotStyle',"compact",'ColorGroup',app.Data.DivisionLogical)
                    title(ax_b, 'Movement for each track in elevation plane (x/y-z)')
                case 'cart'
                    boxplot(ax_t,app.Data.jmp_corr(:,1), {app.Data.DivisionLogical,app.Data.TrackID},"PlotStyle","compact",'ColorGroup',app.Data.DivisionLogical)%,'LabelVerbosity','minor')
                    title(ax_t,'Movement for each track x-axis')
                    boxplot(ax_m,app.Data.jmp_corr(:,2), {app.Data.DivisionLogical,app.Data.TrackID},'PlotStyle','compact','ColorGroup',app.Data.DivisionLogical)
                    title(ax_m,'Movement for each track in y-axis')
                    boxplot(ax_b,app.Data.jmp_corr(:,3), {app.Data.DivisionLogical,app.Data.TrackID},'PlotStyle',"compact",'ColorGroup',app.Data.DivisionLogical)
                    title(ax_b,'Movement for each track in z-axis')
                otherwise
                    disp('error')
            end
            for j=1:3
                ylabel(axx_all(j),'µm')
                xlabel(axx_all(j),'Track')
            end
        end
        
        function analyse_data(app,datatable)
            app.Data = datatable;
            app.StepAnalysisTable = AnalysisStepsMamut(datatable);
            
            app.Framelist = unique(app.Data.frame);
            app.Tracklist = unique(app.Data.TrackID);
            

            app.TrackdivisionLogical = false(length(app.Tracklist),1);
            for k=1:length(app.Tracklist)
                app.TrackdivisionLogical(k) = ~(length(app.Data.frame(app.Data.TrackID == app.Tracklist(k))) == length(unique(app.Data.frame(app.Data.TrackID == app.Tracklist(k)))));
            end
            
            app.NeighbourCalculated = CalculateNeighbourh(app);
            
            app.SisterDistance = zeros(length(app.Framelist),length(app.Tracklist(app.TrackdivisionLogical)));
            app.Data.SisterDistance = zeros(height(app.Data),1);
            losttrack = zeros(length(app.Framelist)*length(app.Tracklist(app.TrackdivisionLogical)),1);
            for i = app.Tracklist(app.TrackdivisionLogical)'
                Data_sub = app.Data(app.Data.TrackID == i,:);
                for t = 1:length(app.Framelist)
                    Data_sub_frame = Data_sub(Data_sub.frame == app.Framelist(t),:);
                    if height(Data_sub_frame) <= 1
                        app.SisterDistance(t,app.Tracklist(app.TrackdivisionLogical) == i) = 0;
                    elseif height(Data_sub_frame) == 2
                        app.SisterDistance(t, app.Tracklist(app.TrackdivisionLogical) == i) = sum(diff(table2array(Data_sub_frame(:,3:5))).^2)^0.5;
                        app.Data.SisterDistance(app.Data.TrackID == i & app.Data.frame == app.Framelist(t)) = repmat(sum(diff(table2array(Data_sub_frame(:,3:5))).^2)^0.5,...
                                                                                size(app.Data.SisterDistance(app.Data.TrackID == i & app.Data.frame == app.Framelist(t))));
                    elseif height(Data_sub_frame)> 2
                        disp("more than 2 sisters see losttrack variable for affected tracks")
                        losttrack(t+(i-1)*length(app.Framelist)) = i;
                    end
                    if ~isempty(unique(losttrack))
                        assignin('base','losttrack',unique(losttrack));
                    end
               end
            end
            app.StepAnalysisTable.DividingTrack = app.TrackdivisionLogical;
            assignin('base','stepanalysis',app.StepAnalysisTable);
        end
        
        function DeltaHistogramPlot(app)
            % calculate a table for delta values (first frame to last frame) in respect to
            % neighbour, two groups dividing and non dividing cells.
    
            %start by nondividing cells
            Tracklist_nonD = app.Tracklist(~app.TrackdivisionLogical);
            moved_non_dividing= zeros(length(Tracklist_nonD),1);
            for j = 1:length(Tracklist_nonD)
               moved_non_dividing(j)= Calculate_moved(app,app.Data(app.Data.TrackID == Tracklist_nonD(j),:),0); 
            end
            [N_nondividing,edges_non]= histcounts(nonzeros(moved_non_dividing),0:5:max(moved_non_dividing)+10);
            % now for dividing cells
            Tracklist_D = app.Tracklist(app.TrackdivisionLogical);
            counter=1;
            moved_dividing = zeros(length(Tracklist_D)*2);
            for l=1:length(Tracklist_D)
                Track_sub = unique(app.Data.TrackID_sub(app.Data.TrackID == Tracklist_D(l)));
        
                if length(Track_sub) > 3
                    disp("more than one division is not supported")
                elseif length(Track_sub) == 3
                
                    Mother = app.Data(app.Data.TrackID_sub == Track_sub(1),:);
                    Daughter1 = app.Data(app.Data.TrackID_sub == Track_sub(2),:);
                    Daughter2 = app.Data(app.Data.TrackID_sub == Track_sub(3),:);
                    moved_dividing(counter) = Calculate_moved(app,[Mother;Daughter1],0);
                    counter = counter+1;
                    moved_dividing(counter) = Calculate_moved(app,[Mother;Daughter2],0);
                    counter = counter+1;
                else
                    disp('encounted a division track where the mother was not in the dataset (set earlier first timepoint to avoid, or accept the error)')
                end
            end
            
            if sum(app.TrackdivisionLogical) ~= 0
                [N_dividing,edges_d]= histcounts(nonzeros(moved_dividing),0:5:max(moved_dividing)+10);
                DeltaHistogram = zeros(3,max([length(edges_non),length(edges_d)]));
                DeltaHistogram(1,1:length(edges_d))=edges_d;
                DeltaHistogram(3,1:length(N_dividing)) = N_dividing;
            else
                DeltaHistogram = zeros(3,length(edges_non));
            end
            DeltaHistogram(1,1:length(edges_non))=edges_non;
            DeltaHistogram(1,:) = DeltaHistogram(1,:) + 2.5;
            DeltaHistogram(2,1:length(N_nondividing)) = N_nondividing;
            %save the delta histogram on the workspace
            assignin('base','DeltaHistogram',DeltaHistogram);
            
            % making the actuall bar plot
            figure;
            b = bar(DeltaHistogram(1,:)',DeltaHistogram(2:3,:)');
            legend({'Non dividing', 'Dividing'})
            b(1).FaceColor = [0 0.4470 0.7410];
            b(2).FaceColor = [0.4660 0.6740 0.1880];
            xlabel 'Distance Delta (µm)'
            ylabel 'counts'
            title 'Delta histogram (compared to nearest neighbour first frame)'

        end
        
        function StepPlot(app)
            % start by dividing the track into 4 categories based on normal
            % or abnormale movement and whether they are dividing
            
            StepResult = app.StepAnalysisTable(app.StepAnalysisTable.track > 0,:);
            NormalNonDividing_count = sum(StepResult.anormal_axes == 0 & StepResult.DividingTrack == false);
            NormalDividing_count = sum(StepResult.anormal_axes == 0 & StepResult.DividingTrack == true);
            AbnormalNonDividing_count = sum(StepResult.anormal_axes == 1 & StepResult.DividingTrack == false);
            AbnormalDividing_count = sum(StepResult.anormal_axes == 1 & StepResult.DividingTrack == true);
            %TrackAnalysisSummary = [NormalNonDividing_count, NormalDividing_count,AbnormalNonDividing_count,AbnormalDividing_count];
            TrackAnalysis = table([NormalNonDividing_count;NormalNonDividing_count/height(StepResult)],...
                                  [NormalDividing_count;NormalDividing_count/height(StepResult)],...
                                  [AbnormalNonDividing_count;AbnormalNonDividing_count/height(StepResult)],...
                                  [AbnormalDividing_count;AbnormalDividing_count/height(StepResult)],...
                                'RowNames',{'numbers','fraction'},...
                                'VariableNames',{'Non-divding Track, Normal movement','Divding Track, Normal movement',...
                                'Non-divding Track, abnormal movement','Divding Track, abnormal movement'});
            assignin('base','TrackAnalysis',TrackAnalysis);
            % draw the actaul barplot
            figure;
            b = bar([table2array(TrackAnalysis(2,:));0 0 0 0],'stacked');
            for i=1:4
                b(i).FaceColor = app.TrackColorScheme(i,:);
            end
            legend(TrackAnalysis.Properties.VariableNames)
            ylabel 'Fraction of track in each category'
            title 'Abnormal vs normal moving tracks'
            xticklabels({'all tracks',''})
            
            % design the plot of explosive movement
            all_steps = zeros(height(StepResult),5,3);
            all_steps(:,:,1) = StepResult.xnumbers;
            all_steps(:,:,2) = StepResult.ynumbers;
            all_steps(:,:,3) = StepResult.znumbers;
            all_steps = all_steps./repmat(StepResult.Steps_in_tracks,[1,5,3]);
            all_steps_summary = sum(all_steps,3);
            x_values = [0.05, 0.01, 0.001 , 0.0001, 0.000001];
            %draw the figures for all the plot
            figure;
            p= semilogx(x_values,all_steps_summary);
            indexes = false(1,4);
            for j=1:length(p)
                if StepResult.anormal_axes(j) > 0
                    if StepResult.DividingTrack(j)
                        p(j).Color = app.TrackColorScheme(4,:);
                        legendplot(4) = p(j);
                        indexes(4) = true;
                    else
                        p(j).Color = app.TrackColorScheme(3,:);
                        legendplot(3) = p(j);
                        indexes(3) = true;
                    end
                else
                    if StepResult.DividingTrack(j)
                        p(j).Color = app.TrackColorScheme(2,:);
                        legendplot(2) = p(j);
                        indexes(2) = true;
                    else
                        p(j).Color = app.TrackColorScheme(1,:);
                        legendplot(1) = p(j);
                        indexes(1) = true;
                    end
                end
            end
            hold on
            % dummy plot to generate the legend
            %legend([p(nonon),p(nodiv),p(abnon),p(abdiv)],TrackAnalysis.Properties.VariableNames)
            legend(legendplot(indexes),TrackAnalysis.Properties.VariableNames(indexes));
            title 'Low probability steps in track'    
            xlabel 'Probability'
            ylabel 'fraction fo steps in each track'

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datatable)
            app.RawData = datatable;
            app.LastFrame=max(unique(datatable.frame));
            app.FirstFrame=min(unique(datatable.frame));
            app.firstframeSlider.Limits= [app.FirstFrame,app.LastFrame];
            app.firstframeSlider.MinorTicks = (app.FirstFrame:1:app.LastFrame);
            app.firstframeSlider.MajorTicks = (app.FirstFrame:5:app.LastFrame);
            app.firstframeSlider.Value = app.FirstFrame;
            app.lastframeSlider.Limits= [app.FirstFrame,app.LastFrame];
            app.lastframeSlider.MinorTicks = (app.FirstFrame:1:app.LastFrame);
            app.lastframeSlider.MajorTicks = (app.FirstFrame:5:app.LastFrame);
            app.lastframeSlider.Value = app.LastFrame;
            app.firsteditField.Value = app.FirstFrame;
            app.lasteditField.Value = app.LastFrame;
            analyse_data(app,app.RawData)
            
                    
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            chossenplot = app.GeneratefigureDropDown.Value;
            switch chossenplot
                case 'Convex Volume'
                    ConvexVolumePlot(app,app.externalfigureButton.Value)
                case 'Sister Distance'
                    SisterDistancePlot(app,app.SisterDistance,app.externalfigureButton.Value)
                    
                case 'Neighbour distance (all)'
                    NeighbourPlot(app,'distall',1)
                case 'Neighbour delta (all)'
                    NeighbourPlot(app,'delta',1)
                case 'Neighbour delta div vs non'
                    NeighbourPlot(app,'del_dvsn',1)
                case 'Movement boxplot (sphere)'
                    movementboxplot(app,'sphere')
                case 'Movement boxplot(xyz)'
                    movementboxplot(app,'cart')
                case 'all (only external)'
                    ConvexVolumePlot(app,1)
                    SisterDistancePlot(app,app.SisterDistance,1)
                    NeighbourPlot(app,'distall',1)
                    NeighbourPlot(app,'delta',1)
                    NeighbourPlot(app,'del_dvsn',1)
                    movementboxplot(app,'sphere')
                    movementboxplot(app,'cart')
                    DeltaHistogramPlot(app)
                    StepPlot(app)
                case 'Delta Histogram'
                    DeltaHistogramPlot(app)
                case 'Analysis of Step'
                    StepPlot(app)
                otherwise
                    disp('error')
            end
        end

        % Value changed function: firsteditField
        function firsteditFieldValueChanged(app, event)
            value = round(app.firsteditField.Value);
            if value > app.LastFrame
                value = app.LastFrame;
                app.firsteditField.Value = value;
            elseif value < app.FirstFrame
                value = app.FirstFrame;
                app.firsteditField.Value = value;
            end
            app.firsteditField.Value = Value;
            app.firstframeSlider.Value=value;
            if value < app.lastframeSlider.Value
                app.Lamp.Color = [1, 0, 0];
                app.cropButton.Enable = 1;
            else
                app.Lamp.Color = [0, 0, 1];
                app.cropButton.Enable = 0;
            end
        end

        % Value changed function: lasteditField
        function lasteditFieldValueChanged(app, event)
            value = round(app.lasteditField.Value);
            if value > app.LastFrame
                value = app.LastFrame;
                app.lasteditField.Value = value;
            elseif value < app.FirstFrame
                value = app.FirstFrame;
                app.lasteditField.Value = value;
            end
            app.lasteditField.Value = value;
            app.lastframeSlider.Value=value;
            if value > app.firstframeSlider.Value
                app.Lamp.Color = [1, 0, 0];
                app.cropButton.Enable = 1;
            else
                app.Lamp.Color = [0, 0, 1];
                app.cropButton.Enable = 0;
            end
            
        end

        % Value changed function: firstframeSlider, lastframeSlider
        function firstframeSliderValueChanged(app, event)
            firstvalue = round(app.firstframeSlider.Value);
            lastvalue = round(app.lastframeSlider.Value);
            app.firsteditField.Value = firstvalue;
            app.lasteditField.Value = lastvalue;
            if lastvalue > firstvalue 
                app.Lamp.Color = [1, 0, 0];
                app.cropButton.Enable = 1;
            else
                app.Lamp.Color = [0, 0, 1];
                app.cropButton.Enable = 0;
            end
            
        end

        % Button pushed function: cropButton
        function cropButtonPushed(app, event)
            firstvalue = round(app.firstframeSlider.Value);
            lastvalue = round(app.lastframeSlider.Value);
            app.Data = app.RawData(app.RawData.frame >= firstvalue & app.RawData.frame <= lastvalue,:);
            analyse_data(app, app.Data);
            app.cropButton.Enable = 0;
            app.Lamp.Color = [0, 1, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeneratefigureDropDownLabel = uilabel(app.UIFigure);
            app.GeneratefigureDropDownLabel.Text = 'Generate figure';
            app.GeneratefigureDropDown = uidropdown(app.UIFigure);
            app.GeneratefigureDropDown.Items = {'Convex Volume', 'Sister Distance', 'Neighbour distance (all)', 'Neighbour delta (all)', 'Neighbour delta div vs non', 'all (only external)', 'Movement boxplot (sphere)', 'Movement boxplot(xyz)', 'Delta Histogram', 'Analysis of Step'};
            app.GeneratefigureDropDown.Value = 'all (only external)';
            app.WheretoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.WheretoplotButtonGroup.Title = 'Where to plot:';
            app.inthisfigureButton = uiradiobutton(app.WheretoplotButtonGroup);
            app.inthisfigureButton.Enable = 'off';
            app.inthisfigureButton.Tooltip = {'currently not implement, coming soon(tm)'};
            app.inthisfigureButton.Text = 'in this figure';
            app.externalfigureButton = uiradiobutton(app.WheretoplotButtonGroup);
            app.externalfigureButton.Text = 'external figure';
            app.externalfigureButton.Value = true;
            app.FiguregeneratorLabel = uilabel(app.UIFigure);
            app.FiguregeneratorLabel.Text = 'Figure generator';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.CroppannelPanel = uipanel(app.UIFigure);
            app.CroppannelPanel.Title = 'Crop pannel';
            app.firstframeSliderLabel = uilabel(app.CroppannelPanel);
            app.firstframeSliderLabel.Text = 'first frame';
            app.firstframeSlider = uislider(app.CroppannelPanel);
            app.firstframeSlider.ValueChangedFcn = createCallbackFcn(app, @firstframeSliderValueChanged, true);
            app.lastframeSliderLabel = uilabel(app.CroppannelPanel);
            app.lastframeSliderLabel.Text = 'last frame';
            app.lastframeSlider = uislider(app.CroppannelPanel);
            app.lastframeSlider.ValueChangedFcn = createCallbackFcn(app, @firstframeSliderValueChanged, true);
            app.cropButton = uibutton(app.CroppannelPanel, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Enable = 'off';
            app.cropButton.Text = 'crop';
            app.Lamp = uilamp(app.CroppannelPanel);
            app.Lamp.Tooltip = {'green = the above crop is applied to the data ;'; 'red = the above crop is not applied to data - pres crop ;'; 'blue = first frame is after last frame fix this'};
            app.firsteditField = uieditfield(app.CroppannelPanel, 'numeric');
            app.firsteditField.ValueChangedFcn = createCallbackFcn(app, @firsteditFieldValueChanged, true);
            app.lasteditField = uieditfield(app.CroppannelPanel, 'numeric');
            app.lasteditField.ValueChangedFcn = createCallbackFcn(app, @lasteditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IlluMamutFigureGenerator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end