classdef IllustrateMamut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        CorrectforcenterofmassCheckBox  matlab.ui.control.CheckBox
        FrameEditField                  matlab.ui.control.NumericEditField
        PreFrameButton                  matlab.ui.control.Button
        NextFrameButton                 matlab.ui.control.Button
        PlayButton                      matlab.ui.control.StateButton
        CalculatemovementCheckBox       matlab.ui.control.CheckBox
        ExportascsvButton               matlab.ui.control.Button
        ImportMaMutDataButton           matlab.ui.control.Button
        VisualizationPanel              matlab.ui.container.Panel
        recenterButton                  matlab.ui.control.Button
        GridCheckBox                    matlab.ui.control.CheckBox
        GridLinesLabel                  matlab.ui.control.Label
        LightingDropDownLabel           matlab.ui.control.Label
        LightingDropDown                matlab.ui.control.DropDown
        camlightDropDownLabel           matlab.ui.control.Label
        camlightDropDown                matlab.ui.control.DropDown
        ShowCenterofMassCheckBox        matlab.ui.control.CheckBox
        ShowmovementlinesCheckBox       matlab.ui.control.CheckBox
        linescalefactorEditFieldLabel   matlab.ui.control.Label
        linescalefactorEditField        matlab.ui.control.NumericEditField
        ColormethodeDropDownLabel       matlab.ui.control.Label
        ColormethodeDropDown            matlab.ui.control.DropDown
        ColorTracksListBoxLabel         matlab.ui.control.Label
        ColorTracksListBox              matlab.ui.control.ListBox
        DataDumpButton                  matlab.ui.control.Button
        GeneratevideoButton             matlab.ui.control.Button
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        sizeinformationPanel            matlab.ui.container.Panel
        ScalingTable                    matlab.ui.control.Table
        unitEditFieldLabel              matlab.ui.control.Label
        unitEditField                   matlab.ui.control.EditField
        ExternalplotButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        SF = 3 %scaling factor
        S_XS %x sphere coordinates for surface render
        S_YS
        S_ZS
        FirstFrame = 0 %first frame
        LastFrame = 1 %last frame
        DataTable
        GraphView = [-37.5 30]
        CM_corrected_calculated = 0 %determine whether the CM corrected drift has been calculated
        Movement_calculated = 0 %
        CMmove  %the move of each center of mass compared to the first frame 
        CenterOfMass 
        FrameList %list of the frames
        ConvVolumes %calculation of the convex volume in each time point
        TrackIDs % a unique list of all the trackID present, used for coloring the spheres in the draw function
        ColormapsTrack % a list of distinqusble colors to color the spheres by trackID
        MoveHistoWindow % Description
        TrackLogical % Description
    end
    
    methods (Access = private)
        
        function DrawFrame(app,varargin)%,ShortSpotList,Canvas)
            ax =app.UIAxes;
            if ~isempty(varargin)
                ax = varargin{1};
                %disp(ax)
            end
            
            
            app.GraphView = app.UIAxes.View;
            %hold off
            Frame_vector = app.DataTable.frame == round(app.FrameSlider.Value);
            x=app.DataTable.pos_x(Frame_vector);
            y=app.DataTable.pos_y(Frame_vector);
            z=app.DataTable.pos_z(Frame_vector);
            tracks = app.DataTable.TrackID(Frame_vector);
            hold(ax,"off")
            if app.ColormethodeDropDown.Value == "Lineage"
                ColormapsTrackFew = distinguishable_colors(length(app.ColorTracksListBox.Value));
                TrackFewID = str2double(app.ColorTracksListBox.Value);
            end
            
            %draw the spheres for each spot
            for k=1:length(x)
                CValue = app.ColormethodeDropDown.Value;
                switch CValue
                    case "Z value"
                        s2=surf(ax,app.S_XS+x(k),app.S_YS+y(k),app.S_ZS+z(k),'EdgeColor',"none");
                    case "Lineage"
                        if sum(TrackFewID == tracks(k)) >= 1
                            s2=surf(ax,app.S_XS+x(k),app.S_YS+y(k),app.S_ZS+z(k),'EdgeColor',"none","FaceColor",ColormapsTrackFew(TrackFewID == tracks(k),:));
                            
                        else
                            s2=surf(ax,app.S_XS+x(k),app.S_YS+y(k),app.S_ZS+z(k),'EdgeColor',"none","FaceColor",[0.7 0.7 0.7]);                            
                        end

                    case "Movement"
                    
                    case "Lineage all"
                        s2=surf(ax,app.S_XS+x(k),app.S_YS+y(k),app.S_ZS+z(k),'EdgeColor',"none","FaceColor",app.ColormapsTrack(app.TrackIDs == tracks(k),:));
                end
%                 s2=surf(app.UIAxes,app.S_XS+x(k),app.S_YS+y(k),app.S_ZS+z(k),'EdgeColor',"none","FaceColor",app.ColormapsTrack(app.TrackIDs == tracks(k),:));
                if k == 1
                    hold(ax,"on")
                end
            end
                        
            camlight(ax,app.camlightDropDown.Value)
            lighting(ax,app.LightingDropDown.Value )
            
            %Determine and draw the center of mass if requested
            if app.ShowCenterofMassCheckBox.Value
                f = find(unique(app.DataTable.frame) == round(app.FrameSlider.Value));
                
                if ~app.CorrectforcenterofmassCheckBox.Value
                    s3 = surf(ax,app.S_XS*3+app.CenterOfMass(f,1),app.S_YS*3+app.CenterOfMass(f,2),app.S_ZS*3+app.CenterOfMass(f,3));
                else
                    s3 = surf(ax,app.S_XS*3+app.CenterOfMass(1,1),app.S_YS*3+app.CenterOfMass(1,2),app.S_ZS*3+app.CenterOfMass(1,3));
                end
                
                
                set(s3,'facecolor', 'green','EdgeColor','none')
            end
            
            %determine and draw movement line if requested
            if app.ShowmovementlinesCheckBox.Value
                scalefactor = round(app.linescalefactorEditField.Value);
                
                if app.CorrectforcenterofmassCheckBox.Value
                    Movement = app.DataTable.jmp_corr(Frame_vector,:);
                else
                    Movement = app.DataTable.jmp(Frame_vector,:);               
                end
                
                for j = 1:length(x)
                    line(ax,[x(j);x(j)+scalefactor*Movement(j,1)],[y(j);y(j)+scalefactor*Movement(j,2)],[z(j);z(j)+scalefactor*Movement(j,3)]);
                end
                        
            end
            
            view(ax,app.GraphView)
        end
        
        function results = VectorLength(~,Vec)
            results = sum(Vec.^2)^0.5;
        end
        
        function [VecPar,VecPer] = VectorProject(app,CM,Pos,jmp_vec)
            CM_vec = Pos-CM;
            CM_vec_len = VectorLength(app,CM_vec);
            VecPar = (sum(CM_vec.*jmp_vec))/(CM_vec_len^2)*CM_vec;
            VecPer = jmp_vec-VecPar;
        end
        
        function results = CalcAngleDifference(~,AngleNew,AngleOld,r)
            %bring angledifference within range -pi to pi
            AngleDif = AngleNew -AngleOld;
            if AngleDif > pi
                AngleDif = AngleDif - 2*pi;
            elseif AngleDif < -pi
                AngleDif = AngleDif + 2*pi;
            end
            results = (AngleDif/(2*pi))*r;
        end
        
        function Calculate_movement(app)
            %converts coordinates to spherical coordiantes, using the
            %corrected coordinates and the CM from frame 1 as 0
            [app.DataTable.Spherical_azimute, app.DataTable.Spherical_elevation, app.DataTable.Spherical_r] = cart2sph(app.DataTable.pos_x_corr - app.CenterOfMass(1,1),...
                                                                    app.DataTable.pos_y_corr - app.CenterOfMass(1,2), app.DataTable.pos_z_corr - app.CenterOfMass(1,3));
            
            jmp_x = zeros(height(app.DataTable),1);
            jmp_y = zeros(height(app.DataTable),1);
            jmp_z = zeros(height(app.DataTable),1);
            jmp_x_corr = zeros(height(app.DataTable),1);
            jmp_y_corr = zeros(height(app.DataTable),1);
            jmp_z_corr = zeros(height(app.DataTable),1);
            jmp_vec_parallel = zeros(height(app.DataTable),3);
            jmp_vec_perpendicular = zeros(height(app.DataTable),3);
            Lengthjmp = zeros(height(app.DataTable),1);
            lengthjmp_corr = zeros(height(app.DataTable),1);
            LengthParallel = zeros(height(app.DataTable),1);
            LengthPerpendicular = zeros(height(app.DataTable),1);
            jmp_vec_parallel_corr = zeros(height(app.DataTable),3);
            jmp_vec_perpendicular_corr = zeros(height(app.DataTable),3);
            LengthParallel_corr = zeros(height(app.DataTable),1);
            LengthPerpendicular_corr = zeros(height(app.DataTable),1);
            %variable to deal with motion on the spherical coordinates
            SphereMove_r = zeros(height(app.DataTable),1);
            SphereMove_azi = zeros(height(app.DataTable),1);
            SphereMove_ele = zeros(height(app.DataTable),1);
            %look into whether the cell is dividing or not
            
            TrackdivisionLogical = false(length(app.TrackIDs),1);
            app.TrackLogical = table('Size', [length(app.TrackIDs) 2], 'VariableNames', {'TrackID','Dividing_Track'}, 'VariableType', {'double','logical'});
            app.TrackLogical.TrackID = app.TrackIDs;
            for k=1:length(app.TrackIDs)
                TrackdivisionLogical(k) = ~(length(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k))) == length(unique(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k)))));
            end
            app.TrackLogical(:,2) = table(TrackdivisionLogical);
            assignin('base','TrackdivisionLogical',app.TrackLogical);
            
            for i=1:height(app.DataTable)
                if app.DataTable.TargetID(i) ~= -1
                    jmp_x(i) = app.DataTable.pos_x_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_x_ori(i);
                    jmp_y(i) = app.DataTable.pos_y_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_y_ori(i);
                    jmp_z(i) = app.DataTable.pos_z_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_z_ori(i);
                    jmp_x_corr(i) = app.DataTable.pos_x_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_x_corr(i);
                    jmp_y_corr(i) = app.DataTable.pos_y_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_y_corr(i);
                    jmp_z_corr(i) = app.DataTable.pos_z_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_z_corr(i);
                    SphereMove_r(i) = app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i))- app.DataTable.Spherical_r(i);
                    SphereMove_azi(i) = CalcAngleDifference(app,app.DataTable.Spherical_azimute(app.DataTable.ID == app.DataTable.TargetID(i)), app.DataTable.Spherical_azimute(i),...
                                                           (app.DataTable.Spherical_r(i)+app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i)))/2);
                    SphereMove_ele(i) = CalcAngleDifference(app,app.DataTable.Spherical_elevation(app.DataTable.ID == app.DataTable.TargetID(i)), app.DataTable.Spherical_elevation(i),...
                                                           (app.DataTable.Spherical_r(i)+app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i)))/2);

                    
                    [jmp_vec_parallel(i,:),jmp_vec_perpendicular(i,:)] = VectorProject(app,app.CenterOfMass(app.FrameList == app.DataTable.frame(i),:),...
                                                                  [app.DataTable.pos_x_ori(i), app.DataTable.pos_y_ori(i),app.DataTable.pos_z_ori(i)],...
                                                                  [jmp_x(i),jmp_y(i),jmp_z(i)]);
%                         disp(temp)
%                         jmp_vec_parallel(i) = temp(1:3);
%                         jmp_vec_perpendicular(i) = temp(4:6);
                                                              
                    [jmp_vec_parallel_corr(i,:),jmp_vec_perpendicular_corr(i,:)] = VectorProject(app,app.CenterOfMass(1,:),...
                                                                  [app.DataTable.pos_x_corr(i), app.DataTable.pos_y_corr(i),app.DataTable.pos_z_corr(i)],...
                                                                  [jmp_x_corr(i),jmp_y_corr(i),jmp_z_corr(i)]);
                       
                    LengthParallel(i) = VectorLength(app,jmp_vec_parallel(i));
                    LengthPerpendicular(i) = VectorLength(app,jmp_vec_perpendicular(i));
                    LengthParallel_corr(i) = VectorLength(app,jmp_vec_parallel_corr(i));
                    LengthPerpendicular_corr(i) = VectorLength(app,jmp_vec_perpendicular_corr(i));
                    Lengthjmp(i) = VectorLength(app,[jmp_x(i) jmp_y(i), jmp_z(i)]);
                    lengthjmp_corr(i) = VectorLength(app,[jmp_x_corr(i) jmp_y_corr(i) jmp_z_corr(i)]);
                                                              
                end
                app.ShowmovementlinesCheckBox.Enable = 1;
                app.linescalefactorEditField.Enable = 1;
            end
            app.Movement_calculated = 1;
%                 disp(size(app.DataTable))
%                 disp(size(jmp_x))
            app.DataTable.jmp = [jmp_x, jmp_y , jmp_z];
            app.DataTable.jmp_corr = [jmp_x_corr, jmp_y_corr,jmp_z_corr];
            app.DataTable.VectorParallel = jmp_vec_parallel;
            app.DataTable.VectorPerpendicular = jmp_vec_perpendicular;
            app.DataTable.VectorParallel_Length = LengthParallel;
            app.DataTable.VectorPerpendicular_Length = LengthPerpendicular;
            app.DataTable.VectorParallel_corr = jmp_vec_parallel_corr;
            app.DataTable.VectorPerpendicular_corr = jmp_vec_perpendicular_corr;
            app.DataTable.VectorParallel_Length_corr = LengthParallel_corr;
            app.DataTable.VectorPerpendicular_Length_corr = LengthPerpendicular_corr;
            app.DataTable.jmp_Length = Lengthjmp;
            app.DataTable.jmp_Length_corr = lengthjmp_corr;
            app.DataTable.SphereMove_r = SphereMove_r;
            app.DataTable.SphereMove_azimute = SphereMove_azi;
            app.DataTable.SphereMove_elevation = SphereMove_ele;
            app.DataTable.DivisionLogical = max(app.DataTable.TrackID == app.TrackIDs(TrackdivisionLogical)',[],2);
            app.ExternalplotButton.Enable = 1;

        end
        
        function Correct_CM(app)
            frames = unique(app.DataTable.frame);
            CM = zeros(length(frames),3);
            ConvVolume = zeros(length(frames),1);
            %distance = zeros(length(frames)-1,1);
              
            for i = 1:length(frames)
                k = frames(i);
                CM(i,1) = (sum(app.DataTable.pos_x(app.DataTable.frame == k)))/length(app.DataTable.pos_x(app.DataTable.frame ==k));
                CM(i,2) = sum(app.DataTable.pos_y(app.DataTable.frame ==k))/length(app.DataTable.pos_y(app.DataTable.frame ==k));
                CM(i,3) = sum(app.DataTable.pos_z(app.DataTable.frame ==k))/length(app.DataTable.pos_z(app.DataTable.frame ==k));
                if i == 1
                    pos_x_corr = app.DataTable.pos_x(app.DataTable.frame == k);
                    pos_y_corr = app.DataTable.pos_y(app.DataTable.frame == k);
                    pos_z_corr = app.DataTable.pos_z(app.DataTable.frame == k);
                    app.CMmove.x = 0;
                    app.CMmove.y = 0;
                    app.CMmove.z = 0;
                else
                   app.CMmove.x(i) = CM(i,1)-CM(1,1);
                   app.CMmove.y(i) = CM(i,2)-CM(1,2);
                   app.CMmove.z(i) = CM(i,3)-CM(1,3);
                   pos_x_corr = cat(1,pos_x_corr,(app.DataTable.pos_x(app.DataTable.frame == k)-app.CMmove.x(i)));
                   pos_y_corr = cat(1,pos_y_corr,(app.DataTable.pos_y(app.DataTable.frame == k)-app.CMmove.y(i)));
                   pos_z_corr = cat(1,pos_z_corr,(app.DataTable.pos_z(app.DataTable.frame == k)-app.CMmove.z(i)));
                end
                posnames = {'pos_x','pos_y','pos_z'};
                cordinates = table2array(app.DataTable(app.DataTable.frame == k,ismember(app.DataTable.Properties.VariableNames,posnames)));
                [~,V]=convhull(cordinates);
                ConvVolume(i)=V;
            end
            app.DataTable.pos_x_corr = pos_x_corr;
            app.DataTable.pos_y_corr = pos_y_corr;
            app.DataTable.pos_z_corr = pos_z_corr;
            %app.CMmove = CMmove ;
            app.CenterOfMass = CM;
            app.ConvVolumes=ConvVolume;
            app.CM_corrected_calculated = 1;
            app.ShowCenterofMassCheckBox.Enable = 1;
            app.CalculatemovementCheckBox.Enable = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SpotList)
%             app.DataTable=SpotList;
%             app.DataTable.pos_x_ori = SpotList.pos_x;
%             app.DataTable.pos_y_ori = SpotList.pos_y;
%             app.DataTable.pos_z_ori = SpotList.pos_z;
%             app.LastFrame=max(unique(app.DataTable.frame));
%             app.FirstFrame=min(unique(app.DataTable.frame));
%             app.FrameList=unique(app.DataTable.frame);
%             app.FrameSlider.Limits= [app.FirstFrame,app.LastFrame];
%             app.FrameSlider.MinorTicks = (app.FirstFrame:1:app.LastFrame);
%             app.FrameSlider.MajorTicks = (app.FirstFrame:5:app.LastFrame);
%             app.FrameSlider.Value = app.FirstFrame;
%             [Sphere_XS,Sphere_YS,Sphere_ZS] = sphere; % there might be a bug here, but I can't assign it with []
%             app.UIAxes.XLim = [0 max(app.DataTable.pos_x)+200];
%             app.UIAxes.YLim = [0 max(app.DataTable.pos_y)+200];
%             app.UIAxes.ZLim = [0 max(app.DataTable.pos_z)+100];
%             app.UIAxes.View = app.GraphView;
%             app.UIAxes.XGrid = 'on';
%             app.UIAxes.YGrid = 'on';
%             app.UIAxes.ZGrid = 'on';
%             app.S_XS = Sphere_XS.*app.SF;
%             app.S_YS = Sphere_YS.*app.SF;
%             app.S_ZS = Sphere_ZS.*app.SF;
%             DrawFrame(app)%,app.DataTable(app.DataTable.frame == app.FirstFrame,:),app.UIAxes);
             app.VisualizationPanel.Visible = 0;
        end

        % Value changed function: LightingDropDown
        function LightingDropDownValueChanged(app, event)
            DrawFrame(app)
            
        end

        % Value changed function: camlightDropDown
        function camlightDropDownValueChanged(app, event)
            DrawFrame(app);
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            DrawFrame(app);
            app.FrameEditField.Value = round(app.FrameSlider.Value);
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.ZGrid = 'on';
            else
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
                app.UIAxes.ZGrid = 'off';
            end
            DrawFrame(app)
        end

        % Button pushed function: recenterButton
        function recenterButtonPushed(app, event)
            app.UIAxes.XLim = [0 max(app.DataTable.pos_x)+200];
            app.UIAxes.YLim = [0 max(app.DataTable.pos_y)+200];
            app.UIAxes.ZLim = [0 max(app.DataTable.pos_z)+100];
            app.GraphView = [-37.5 30];
            app.UIAxes.View = app.GraphView;
            DrawFrame(app)
        end

        % Value changed function: CorrectforcenterofmassCheckBox
        function CorrectforcenterofmassCheckBoxValueChanged(app, event)
            if ~app.CM_corrected_calculated
                disp('something went wrong')
                frames = unique(app.DataTable.frame);
                CM = zeros(length(frames),3);
                ConvVolume = zeros(length(frames),1);
                %distance = zeros(length(frames)-1,1);
                  
                for i = 1:length(frames)
                    k = frames(i);
                    CM(i,1) = (sum(app.DataTable.pos_x(app.DataTable.frame == k)))/length(app.DataTable.pos_x(app.DataTable.frame ==k));
                    CM(i,2) = sum(app.DataTable.pos_y(app.DataTable.frame ==k))/length(app.DataTable.pos_y(app.DataTable.frame ==k));
                    CM(i,3) = sum(app.DataTable.pos_z(app.DataTable.frame ==k))/length(app.DataTable.pos_z(app.DataTable.frame ==k));
                    if i == 1
                        pos_x_corr = app.DataTable.pos_x(app.DataTable.frame == k);
                        pos_y_corr = app.DataTable.pos_y(app.DataTable.frame == k);
                        pos_z_corr = app.DataTable.pos_z(app.DataTable.frame == k);
                        app.CMmove.x = 0;
                        app.CMmove.y = 0;
                        app.CMmove.z = 0;
                    else
                       app.CMmove.x(i) = CM(i,1)-CM(1,1);
                       app.CMmove.y(i) = CM(i,2)-CM(1,2);
                       app.CMmove.z(i) = CM(i,3)-CM(1,3);
                       pos_x_corr = cat(1,pos_x_corr,(app.DataTable.pos_x(app.DataTable.frame == k)-app.CMmove.x(i)));
                       pos_y_corr = cat(1,pos_y_corr,(app.DataTable.pos_y(app.DataTable.frame == k)-app.CMmove.y(i)));
                       pos_z_corr = cat(1,pos_z_corr,(app.DataTable.pos_z(app.DataTable.frame == k)-app.CMmove.z(i)));
                    end
                    posnames = {'pos_x','pos_y','pos_z'};
                    cordinates = table2array(app.DataTable(app.DataTable.frame == k,ismember(app.DataTable.Properties.VariableNames,posnames)));
                    [~,V]=convhull(cordinates);
                    ConvVolume(i)=V;
                end
                app.DataTable.pos_x_corr = pos_x_corr;
                app.DataTable.pos_y_corr = pos_y_corr;
                app.DataTable.pos_z_corr = pos_z_corr;
                %app.CMmove = CMmove ;
                app.CenterOfMass = CM;
                app.ConvVolumes=ConvVolume;
                app.CM_corrected_calculated = 1;
                app.ShowCenterofMassCheckBox.Enable = 1;
                app.CalculatemovementCheckBox.Enable = 1;
                
            end
        
            value = app.CorrectforcenterofmassCheckBox.Value;
            if value
                app.DataTable.pos_x = app.DataTable.pos_x_corr;
                app.DataTable.pos_y = app.DataTable.pos_y_corr;
                app.DataTable.pos_z = app.DataTable.pos_z_corr;
                DrawFrame(app)
            else
                app.DataTable.pos_x = app.DataTable.pos_x_ori;
                app.DataTable.pos_y = app.DataTable.pos_y_ori;
                app.DataTable.pos_z = app.DataTable.pos_z_ori;
                DrawFrame(app)
            end
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            NewFrame=round(app.FrameSlider.Value)+1;
            if NewFrame > app.LastFrame
                NewFrame = app.FirstFrame;
            end
            app.FrameSlider.Value = NewFrame;
            app.FrameEditField.Value = NewFrame;
            DrawFrame(app)
        end

        % Button pushed function: PreFrameButton
        function PreFrameButtonPushed(app, event)
            NewFrame=round(app.FrameSlider.Value)-1;
            if NewFrame < app.FirstFrame
                NewFrame = app.LastFrame;
            end
            app.FrameSlider.Value = NewFrame;
            app.FrameEditField.Value = NewFrame;
            DrawFrame(app)
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            if value > app.LastFrame
                value = app.LastFrame;
                app.FrameEditField.Value = value;
            elseif value < app.FirstFrame
                value = app.FirstFrame;
                app.FrameEditField.Value = value;
            end
            app.FrameSlider.Value=value;
            DrawFrame(app)
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            while app.PlayButton.Value
                NewFrame=round(app.FrameSlider.Value)+1;
                if NewFrame > app.LastFrame
                    NewFrame = app.FirstFrame;
                end
                app.FrameSlider.Value = NewFrame;
                app.FrameEditField.Value = NewFrame;
                DrawFrame(app)
                pause(0.5)
            end
            
        end

        % Value changed function: ShowCenterofMassCheckBox
        function ShowCenterofMassCheckBoxValueChanged(app, event)
            DrawFrame(app)
            
        end

        % Value changed function: CalculatemovementCheckBox
        function CalculatemovementCheckBoxValueChanged(app, event)
            if ~app.Movement_calculated
                disp('something went wrong with movement')
                %converts coordinates to spherical coordiantes, using the
                %corrected coordinates and the CM from frame 1 as 0
                [app.DataTable.Spherical_azimute, app.DataTable.Spherical_elevation, app.DataTable.Spherical_r] = cart2sph(app.DataTable.pos_x_corr - app.CenterOfMass(1,1),...
                                                                        app.DataTable.pos_y_corr - app.CenterOfMass(1,2), app.DataTable.pos_z_corr - app.CenterOfMass(1,3));
                
                jmp_x = zeros(height(app.DataTable),1);
                jmp_y = zeros(height(app.DataTable),1);
                jmp_z = zeros(height(app.DataTable),1);
                jmp_x_corr = zeros(height(app.DataTable),1);
                jmp_y_corr = zeros(height(app.DataTable),1);
                jmp_z_corr = zeros(height(app.DataTable),1);
                jmp_vec_parallel = zeros(height(app.DataTable),3);
                jmp_vec_perpendicular = zeros(height(app.DataTable),3);
                Lengthjmp = zeros(height(app.DataTable),1);
                lengthjmp_corr = zeros(height(app.DataTable),1);
                LengthParallel = zeros(height(app.DataTable),1);
                LengthPerpendicular = zeros(height(app.DataTable),1);
                jmp_vec_parallel_corr = zeros(height(app.DataTable),3);
                jmp_vec_perpendicular_corr = zeros(height(app.DataTable),3);
                LengthParallel_corr = zeros(height(app.DataTable),1);
                LengthPerpendicular_corr = zeros(height(app.DataTable),1);
                %variable to deal with motion on the spherical coordinates
                SphereMove_r = zeros(height(app.DataTable),1);
                SphereMove_azi = zeros(height(app.DataTable),1);
                SphereMove_ele = zeros(height(app.DataTable),1);
                %look into whether the cell is dividing or not
                
                TrackdivisionLogical = false(length(app.TrackIDs),1);
                app.TrackLogical = table('Size', [length(app.TrackIDs) 2], 'VariableNames', {'TrackID','Dividing_Track'}, 'VariableType', {'double','logical'});
                app.TrackLogical.TrackID = app.TrackIDs;
                for k=1:length(app.TrackIDs)
                    TrackdivisionLogical(k) = ~(length(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k))) == length(unique(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k)))));
                end
                app.TrackLogical(:,2) = table(TrackdivisionLogical);
                assignin('base','TrackdivisionLogical',app.TrackLogical);
                
                for i=1:height(app.DataTable)
                    if app.DataTable.TargetID(i) ~= -1
                        jmp_x(i) = app.DataTable.pos_x_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_x_ori(i);
                        jmp_y(i) = app.DataTable.pos_y_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_y_ori(i);
                        jmp_z(i) = app.DataTable.pos_z_ori(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_z_ori(i);
                        jmp_x_corr(i) = app.DataTable.pos_x_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_x_corr(i);
                        jmp_y_corr(i) = app.DataTable.pos_y_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_y_corr(i);
                        jmp_z_corr(i) = app.DataTable.pos_z_corr(app.DataTable.ID == app.DataTable.TargetID(i)) - app.DataTable.pos_z_corr(i);
                        SphereMove_r(i) = app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i))- app.DataTable.Spherical_r(i);
                        SphereMove_azi(i) = CalcAngleDifference(app,app.DataTable.Spherical_azimute(app.DataTable.ID == app.DataTable.TargetID(i)), app.DataTable.Spherical_azimute(i),...
                                                               (app.DataTable.Spherical_r(i)+app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i)))/2);
                        SphereMove_ele(i) = CalcAngleDifference(app,app.DataTable.Spherical_elevation(app.DataTable.ID == app.DataTable.TargetID(i)), app.DataTable.Spherical_elevation(i),...
                                                               (app.DataTable.Spherical_r(i)+app.DataTable.Spherical_r(app.DataTable.ID == app.DataTable.TargetID(i)))/2);

                        
                        [jmp_vec_parallel(i,:),jmp_vec_perpendicular(i,:)] = VectorProject(app,app.CenterOfMass(app.FrameList == app.DataTable.frame(i),:),...
                                                                      [app.DataTable.pos_x_ori(i), app.DataTable.pos_y_ori(i),app.DataTable.pos_z_ori(i)],...
                                                                      [jmp_x(i),jmp_y(i),jmp_z(i)]);
%                         disp(temp)
%                         jmp_vec_parallel(i) = temp(1:3);
%                         jmp_vec_perpendicular(i) = temp(4:6);
                                                                  
                        [jmp_vec_parallel_corr(i,:),jmp_vec_perpendicular_corr(i,:)] = VectorProject(app,app.CenterOfMass(1,:),...
                                                                      [app.DataTable.pos_x_corr(i), app.DataTable.pos_y_corr(i),app.DataTable.pos_z_corr(i)],...
                                                                      [jmp_x_corr(i),jmp_y_corr(i),jmp_z_corr(i)]);
                           
                        LengthParallel(i) = VectorLength(app,jmp_vec_parallel(i));
                        LengthPerpendicular(i) = VectorLength(app,jmp_vec_perpendicular(i));
                        LengthParallel_corr(i) = VectorLength(app,jmp_vec_parallel_corr(i));
                        LengthPerpendicular_corr(i) = VectorLength(app,jmp_vec_perpendicular_corr(i));
                        Lengthjmp(i) = VectorLength(app,[jmp_x(i) jmp_y(i), jmp_z(i)]);
                        lengthjmp_corr(i) = VectorLength(app,[jmp_x_corr(i) jmp_y_corr(i) jmp_z_corr(i)]);
                                                                  
                    end
                    app.ShowmovementlinesCheckBox.Enable = 1;
                    app.linescalefactorEditField.Enable = 1;
                end
                app.Movement_calculated = 1;
%                 disp(size(app.DataTable))
%                 disp(size(jmp_x))
                app.DataTable.jmp = [jmp_x, jmp_y , jmp_z];
                app.DataTable.jmp_corr = [jmp_x_corr, jmp_y_corr,jmp_z_corr];
                app.DataTable.VectorParallel = jmp_vec_parallel;
                app.DataTable.VectorPerpendicular = jmp_vec_perpendicular;
                app.DataTable.VectorParallel_Length = LengthParallel;
                app.DataTable.VectorPerpendicular_Length = LengthPerpendicular;
                app.DataTable.VectorParallel_corr = jmp_vec_parallel_corr;
                app.DataTable.VectorPerpendicular_corr = jmp_vec_perpendicular_corr;
                app.DataTable.VectorParallel_Length_corr = LengthParallel_corr;
                app.DataTable.VectorPerpendicular_Length_corr = LengthPerpendicular_corr;
                app.DataTable.jmp_Length = Lengthjmp;
                app.DataTable.jmp_Length_corr = lengthjmp_corr;
                app.DataTable.SphereMove_r = SphereMove_r;
                app.DataTable.SphereMove_azimute = SphereMove_azi;
                app.DataTable.SphereMove_elevation = SphereMove_ele;
                app.DataTable.DivisionLogical = max(app.DataTable.TrackID == app.TrackIDs(TrackdivisionLogical)',[],2);

            end
            
        end

        % Button pushed function: ExportascsvButton
        function ExportascsvButtonPushed(app, event)
            [file,path,indx] = uiputfile('.csv','Save as...');
            if indx
                writetable(app.DataTable,[path file])
            end
        end

        % Button pushed function: ImportMaMutDataButton
        function ImportMaMutDataButtonPushed(app, event)
            %here we load the data:
            [file,path,indx] = uigetfile('*.xml','Load the MaMut xml file..');
            
            if indx
                %making waiting bar
                f = uifigure;       
                d = uiprogressdlg(f,'Title','Please wait','Message','Calculating');
                d.Value = 0.1;
                [SpotList,~,~,scaling] = MamutXML2Matlab([path, file],app.UIFigure.Position);
                app.ScalingTable.Data = [scaling{1},scaling{2},scaling{3}];
                app.unitEditField.Value = scaling{4};
                app.DataTable=SpotList;
                app.DataTable.pos_x_ori = SpotList.pos_x;
                app.DataTable.pos_y_ori = SpotList.pos_y;
                app.DataTable.pos_z_ori = SpotList.pos_z;
                app.LastFrame=max(unique(app.DataTable.frame));
                app.FirstFrame=min(unique(app.DataTable.frame));
                app.FrameList=unique(app.DataTable.frame);
                app.FrameSlider.Limits= [app.FirstFrame,app.LastFrame];
                app.FrameSlider.MinorTicks = (app.FirstFrame:1:app.LastFrame);
                app.FrameSlider.MajorTicks = (app.FirstFrame:5:app.LastFrame);
                app.FrameSlider.Value = app.FirstFrame;
                [Sphere_XS,Sphere_YS,Sphere_ZS] = sphere;
                app.UIAxes.XLim = [0 max(app.DataTable.pos_x)+200];
                app.UIAxes.YLim = [0 max(app.DataTable.pos_y)+200];
                app.UIAxes.ZLim = [0 max(app.DataTable.pos_z)+100];
                app.UIAxes.View = app.GraphView;
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.ZGrid = 'on';
                app.S_XS = Sphere_XS.*app.SF;
                app.S_YS = Sphere_YS.*app.SF;
                app.S_ZS = Sphere_ZS.*app.SF;
                app.TrackIDs=unique(SpotList.TrackID);
                %is this being calculated twice?
                TrackdivisionLogical = false(length(app.TrackIDs),1);
                for k=1:length(app.TrackIDs)
                    TrackdivisionLogical(k) = ~(length(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k))) == length(unique(app.DataTable.frame(app.DataTable.TrackID == app.TrackIDs(k)))));
                end
                %end of maybe double calculation
                [~,I] = sort(TrackdivisionLogical,'descend');
                app.ColorTracksListBox.Items = string(app.TrackIDs(I));
                app.ColorTracksListBox.Value = app.ColorTracksListBox.Items(TrackdivisionLogical(I));
                app.ColormapsTrack=distinguishable_colors(length(app.TrackIDs));
                d.Value = 0.3;
                d.Message = 'Drawing first frame ';
                DrawFrame(app)
                d.Value = 0.5;
                d.Message = 'Correct for center of Mass';
                Correct_CM(app);
                d.Value = 0.8;
                d.Message = 'Calculate movement';
                Calculate_movement(app);
                d.Value = 1;
                d.Message = 'I am done, thank you for waiting :D';
                pause(1)
                close(d)
                close(f)
            end
            app.VisualizationPanel.Visible = 1;
        end

        % Value changed function: ShowmovementlinesCheckBox
        function ShowmovementlinesCheckBoxValueChanged(app, event)
            DrawFrame(app)
            
        end

        % Value changed function: ColormethodeDropDown
        function ColormethodeDropDownValueChanged(app, event)
            value = app.ColormethodeDropDown.Value;
            switch value
                case "Z value"
                    app.ColorTracksListBox.Visible = 0;
                    %app.OHTER.Visible = 0;
                case "Lineage"
                    app.ColorTracksListBox.Visible = 1;
                    %app.OHTER.Visible = 0;
                case "Movement"
                    disp("Under Development")
                    app.ColorTracksListBox.Visible = 0;
                    %app.OHTER.Visible = 1;
            end
            DrawFrame(app)
        end

        % Button pushed function: DataDumpButton
        function DataDumpButtonPushed(app, event)
            assignin("base","Data",app.DataTable)
        end

        % Value changed function: ColorTracksListBox
        function ColorTracksListBoxValueChanged(app, event)
%             value = app.ColorTracksListBox.Value;
%             assignin("base",'value',value)
              DrawFrame(app)
        end

        % Button pushed function: GeneratevideoButton
        function GeneratevideoButtonPushed(app, event)
            [file,path,idx] = uiputfile('*.mp4','save filename','New_movie.mp4');
            if ~idx
                return
            end
            app.FrameEditField.Value = app.FirstFrame;
            FrameEditFieldValueChanged(app)
            vidfile = VideoWriter([path file],"MPEG-4");
            vidfile.FrameRate = round(app.FPSEditField.Value);
            vidfile.Quality = 100;
            open(vidfile);
            f = figure(4);
            f.Position = [0 0 1400 1080];
            f.Color = 'white';
            ax = gca(f);
            ax.DataAspectRatioMode = 'manual';

            for k=1:length(app.FrameList)

               DrawFrame(app,ax)
               ax.XLim = app.UIAxes.XLim;
               ax.YLim = app.UIAxes.YLim;
               ax.ZLim = app.UIAxes.ZLim;
               ax.View = app.UIAxes.View;
               ax.DataAspectRatio = app.UIAxes.DataAspectRatio;
               IM=getframe(f);
               writeVideo(vidfile,IM)
               NextFrameButtonPushed(app) 
            end
            close(vidfile)

            
        end

        % Button pushed function: ExternalplotButton
        function ExternalplotButtonPushed(app, event)
            IlluMamutFigureGenerator(app.DataTable)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image region')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.CorrectforcenterofmassCheckBox = uicheckbox(app.UIFigure);
            app.CorrectforcenterofmassCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectforcenterofmassCheckBoxValueChanged, true);
            app.CorrectforcenterofmassCheckBox.Text = 'Correct for center of mass';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.PreFrameButton = uibutton(app.UIFigure, 'push');
            app.PreFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreFrameButtonPushed, true);
            app.PreFrameButton.Text = '<';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = '>';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.CalculatemovementCheckBox = uicheckbox(app.UIFigure);
            app.CalculatemovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatemovementCheckBoxValueChanged, true);
            app.CalculatemovementCheckBox.Enable = 'off';
            app.CalculatemovementCheckBox.Tooltip = {'Calculate correct for center of mass first'};
            app.CalculatemovementCheckBox.Text = 'Calculate movement';
            app.ExportascsvButton = uibutton(app.UIFigure, 'push');
            app.ExportascsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportascsvButtonPushed, true);
            app.ExportascsvButton.Text = 'Export as csv';
            app.ImportMaMutDataButton = uibutton(app.UIFigure, 'push');
            app.ImportMaMutDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMaMutDataButtonPushed, true);
            app.ImportMaMutDataButton.Text = 'Import MaMut-Data';
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.recenterButton = uibutton(app.VisualizationPanel, 'push');
            app.recenterButton.ButtonPushedFcn = createCallbackFcn(app, @recenterButtonPushed, true);
            app.recenterButton.Text = 'recenter';
            app.GridCheckBox = uicheckbox(app.VisualizationPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = {''; ''};
            app.GridCheckBox.Value = true;
            app.GridLinesLabel = uilabel(app.VisualizationPanel);
            app.GridLinesLabel.Text = 'Grid Lines';
            app.LightingDropDownLabel = uilabel(app.VisualizationPanel);
            app.LightingDropDownLabel.Text = 'Lighting';
            app.LightingDropDown = uidropdown(app.VisualizationPanel);
            app.LightingDropDown.Items = {'flat', 'gouraud', 'none', ''};
            app.LightingDropDown.ValueChangedFcn = createCallbackFcn(app, @LightingDropDownValueChanged, true);
            app.LightingDropDown.Value = 'flat';
            app.camlightDropDownLabel = uilabel(app.VisualizationPanel);
            app.camlightDropDownLabel.Text = 'camlight';
            app.camlightDropDown = uidropdown(app.VisualizationPanel);
            app.camlightDropDown.Items = {'headlight', 'right', 'left', ''};
            app.camlightDropDown.ValueChangedFcn = createCallbackFcn(app, @camlightDropDownValueChanged, true);
            app.camlightDropDown.Value = 'headlight';
            app.ShowCenterofMassCheckBox = uicheckbox(app.VisualizationPanel);
            app.ShowCenterofMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCenterofMassCheckBoxValueChanged, true);
            app.ShowCenterofMassCheckBox.Enable = 'off';
            app.ShowCenterofMassCheckBox.Text = 'Show Center of Mass';
            app.ShowmovementlinesCheckBox = uicheckbox(app.VisualizationPanel);
            app.ShowmovementlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmovementlinesCheckBoxValueChanged, true);
            app.ShowmovementlinesCheckBox.Enable = 'off';
            app.ShowmovementlinesCheckBox.Text = 'Show movement lines';
            app.linescalefactorEditFieldLabel = uilabel(app.VisualizationPanel);
            app.linescalefactorEditFieldLabel.Enable = 'off';
            app.linescalefactorEditFieldLabel.Text = 'line scale factor';
            app.linescalefactorEditField = uieditfield(app.VisualizationPanel, 'numeric');
            app.linescalefactorEditField.Limits = [1 100];
            app.linescalefactorEditField.Enable = 'off';
            app.linescalefactorEditField.Value = 10;
            app.ColormethodeDropDownLabel = uilabel(app.VisualizationPanel);
            app.ColormethodeDropDownLabel.Text = 'Color methode';
            app.ColormethodeDropDown = uidropdown(app.VisualizationPanel);
            app.ColormethodeDropDown.Items = {'Z value', 'Lineage', 'Movement', 'Lineage all'};
            app.ColormethodeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormethodeDropDownValueChanged, true);
            app.ColormethodeDropDown.Value = 'Z value';
            app.ColorTracksListBoxLabel = uilabel(app.UIFigure);
            app.ColorTracksListBoxLabel.Visible = 'off';
            app.ColorTracksListBoxLabel.Text = 'Color Tracks';
            app.ColorTracksListBox = uilistbox(app.UIFigure);
            app.ColorTracksListBox.Items = {'Item 1', 'Item 2', 'Item 5', 'Item 3', 'Item 4'};
            app.ColorTracksListBox.Multiselect = 'on';
            app.ColorTracksListBox.ValueChangedFcn = createCallbackFcn(app, @ColorTracksListBoxValueChanged, true);
            app.ColorTracksListBox.Visible = 'off';
            app.ColorTracksListBox.Tooltip = {'select which track to color'};
            app.ColorTracksListBox.Value = {'Item 1'};
            app.DataDumpButton = uibutton(app.UIFigure, 'push');
            app.DataDumpButton.ButtonPushedFcn = createCallbackFcn(app, @DataDumpButtonPushed, true);
            app.DataDumpButton.Tooltip = {'Dump the data file to the matlab workspace'};
            app.DataDumpButton.Text = 'Data Dump';
            app.GeneratevideoButton = uibutton(app.UIFigure, 'push');
            app.GeneratevideoButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideoButtonPushed, true);
            app.GeneratevideoButton.Text = {'Generate video'; ''};
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPSEditField.Limits = [0 Inf];
            app.FPSEditField.Value = 2;
            app.sizeinformationPanel = uipanel(app.UIFigure);
            app.sizeinformationPanel.Title = 'size information';
            app.ScalingTable = uitable(app.sizeinformationPanel);
            app.ScalingTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.ScalingTable.RowName = {''};
            app.unitEditFieldLabel = uilabel(app.sizeinformationPanel);
            app.unitEditFieldLabel.Text = 'unit';
            app.unitEditField = uieditfield(app.sizeinformationPanel, 'text');
            app.unitEditField.Editable = 'off';
            app.unitEditField.Value = 'Pixels';
            app.ExternalplotButton = uibutton(app.UIFigure, 'push');
            app.ExternalplotButton.ButtonPushedFcn = createCallbackFcn(app, @ExternalplotButtonPushed, true);
            app.ExternalplotButton.Enable = 'off';
            app.ExternalplotButton.Tooltip = {'check the center of mass and movement first'};
            app.ExternalplotButton.Text = 'External plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IllustrateMamut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end